classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        STARTButton               matlab.ui.control.Button
        HEARTRATEMONITORINGLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        FREQUENCYHzTextArea       matlab.ui.control.TextArea
        FREQUENCYHzTextAreaLabel  matlab.ui.control.Label
        BPMTextArea               matlab.ui.control.TextArea
        BPMTextAreaLabel          matlab.ui.control.Label
        FreqGraph                 matlab.ui.control.UIAxes
        TimeDomain                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        Property % Description
            fsampling = 128;% declaring sampling frequency
            sensorValues = [];% we are collecting sensor data in this vector
            len_data = 0;% variable that stores current lenght of data
            current_index = 0;% stores current data index
            freq_value=0.0; % stores current frequency value
            bpm=0; % stores current bpm value
            hLine;  % animated line for time domain plot
            hFreqLine;  % animated line for freq domain plot
            error_filter = 0.26; % error rate for dominant frequency calculation

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
           
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)


            s = serialport("/dev/cu.wchusbserial14400", 115200); % connecting serial port
            app.hLine = animatedline(app.TimeDomain);% initialization of animated line for time domain plot
            app.hFreqLine = animatedline(app.FreqGraph);% initialization of animated line for freq domain plot

           
            % Real time data reading and plotting
            while ishandle(app.hLine)
                app.len_data = app.len_data + 1; % we are increasing lenght of data every iteration

                data = str2double(readline(s)); % read the data from serial port
                app.sensorValues = [app.sensorValues  data]; % add the data to sensor value vector
                app.current_index = app.current_index +1; %update current index

                
                addpoints(app.hLine, app.current_index/app.fsampling, data);  % add the new sensor data to plot  
                xlim(app.TimeDomain,[app.current_index/app.fsampling - 0.5, app.current_index/app.fsampling + 0.5]); % dynamic x axes according to current index. we also divide current index to sampling freq to obtain sampling time
                drawnow limitrate;


                fftLen = 256;%fft lenght
                if app.len_data>fftLen+1   % if data collected
                 
                    % Performing Fourier transform
                    dataToFFT = detrend(app.sensorValues(end-fftLen+1:end)); % fft for last 256 data.
                    fftResult = fft(dataToFFT);
                    fftResult = fftshift(abs(fftResult));  % Shift zero frequency component to the center
                    fftFrequency = linspace(-app.fsampling/2, app.fsampling/2, fftLen);

                    % Update the frequency-domain plot
                    clearpoints(app.hFreqLine);
                    addpoints(app.hFreqLine, fftFrequency, fftResult);
                    xlim(app.FreqGraph,[-app.fsampling/2, app.fsampling/2]);
                    drawnow limitrate;

                    indice = find(abs(fftResult)==max(abs(fftResult)));   %finding max freq value in freq domain
                    if length(indice)>1
                        app.freq_value = fftFrequency(indice(end))- app.error_filter;  % obtaining frequency
                        app.bpm  = round(app.freq_value*60); % obtainin bpm 
                        app.FREQUENCYHzTextArea.Value = string(app.freq_value); %print freq to text area
                        app.BPMTextArea.Value = string(app.bpm); %print bpm to text area

                    end

                end

            end

            % close serial communication
            delete(s);
            clear s;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeDomain = uiaxes(app.UIFigure);
            xlabel(app.TimeDomain, 'Time')
            ylabel(app.TimeDomain, 'Sensor Value')
            zlabel(app.TimeDomain, 'Z')
            app.FreqGraph = uiaxes(app.UIFigure);
            xlabel(app.FreqGraph, 'Frequency(Hz)')
            ylabel(app.FreqGraph, 'Amplitude')
            zlabel(app.FreqGraph, 'Z')
            app.BPMTextAreaLabel = uilabel(app.UIFigure);
            app.BPMTextAreaLabel.Text = 'BPM';
            app.BPMTextArea = uitextarea(app.UIFigure);
            app.FREQUENCYHzTextAreaLabel = uilabel(app.UIFigure);
            app.FREQUENCYHzTextAreaLabel.Text = 'FREQUENCY(Hz)';
            app.FREQUENCYHzTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'heart_image.png');
            app.HEARTRATEMONITORINGLabel = uilabel(app.UIFigure);
            app.HEARTRATEMONITORINGLabel.Text = 'HEART RATE MONITORING ';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end